classdef Proiect_Meva_Hakan_321AC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        IdentificareasistemelorLabel  matlab.ui.control.Label
        RulareButton                  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        EroareEditFieldLabel          matlab.ui.control.Label
        EroareEditField               matlab.ui.control.NumericEditField
        AfisareeroareButton           matlab.ui.control.Button
    end


    properties (Access = public)
        eroare % Description
    end


    methods (Access = private)

        % Button pushed function: RulareButton
        function RulareButtonPushed(app, event)
            
N = 200;
S1=0;
S2=0;
output2=zeros(N,1);
input = sort((200-1)*rand(N,1)+1,'descend');

output1 = rand(N,1);
inputmatr = toeplitz(input,[input(1),zeros(1,N-1)]);


%x = inputmatr\output1;
auxtau=0;
[R,U,beta]=TORT(inputmatr);
[m,n]=size(inputmatr);

for k=1:n
    for i=k:m
        auxtau=auxtau+U(i,k)*output1(i);
    end
    tau=auxtau/beta(k);
    for i=k:m
        output1(i)=output1(i)-tau*U(i,k);
    end
end
x=UTRIS(R(1:n,:),output1(1:n));


for i=1:N
    for j=1:i
        output2(i)=output2(i)+inputmatr(i,i+1-j)*x(j);
    end
end


plot(app.UIAxes,input,output1,'r')

plot(app.UIAxes2,input,output2,'g')

for v=1:N
    S1=S1+abs(output1(v));
    S2=S2+abs(output2(v));
end
S1=S1/N;
S2=S2/N;
aux=abs(S1-S2);
app.eroare=aux/S1*100


clear all

        end

        % Button pushed function: AfisareeroareButton
        function EroareEditFieldValueChanged(app, event)
            app.EroareEditField.Value=app.eroare;
            value = app.EroareEditField.Value;
            
            
        end

        % Callback function
        function AfisareeroareButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.IdentificareasistemelorLabel = uilabel(app.UIFigure);
            app.IdentificareasistemelorLabel.Text = 'Identificarea sistemelor';
            app.RulareButton = uibutton(app.UIFigure, 'push');
            app.RulareButton.ButtonPushedFcn = createCallbackFcn(app, @RulareButtonPushed, true);
            app.RulareButton.Text = 'Rulare';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafic teoretic')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Grafic modelat')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.EroareEditFieldLabel = uilabel(app.UIFigure);
            app.EroareEditFieldLabel.Text = 'Eroare';
            app.EroareEditField = uieditfield(app.UIFigure, 'numeric');
            app.AfisareeroareButton = uibutton(app.UIFigure, 'push');
            app.AfisareeroareButton.ButtonPushedFcn = createCallbackFcn(app, @EroareEditFieldValueChanged, true);
            app.AfisareeroareButton.Text = 'Afisare eroare';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Proiect_Meva_Hakan_321AC

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end